classdef dist2conv_gui < matlab.apps.AppBase

   % Properties that correspond to app components
   properties (Access = public)
      dist2convUIFigure   matlab.ui.Figure
      IPDLabel            matlab.ui.control.Label
      IPD                 matlab.ui.control.NumericEditField
      verge_angleLabel    matlab.ui.control.Label
      verge_angle         matlab.ui.control.NumericEditField
      DistancetotgtLabel  matlab.ui.control.Label
      distance            matlab.ui.control.NumericEditField
      verge_dioptLabel    matlab.ui.control.Label
      verge_diopt         matlab.ui.control.NumericEditField
   end

   
   methods (Access = private)
      
      function results = saveprefs(app)
         olddir=pwd;
         wpos=app.dist2convUIFigure.Position;
         wpos=wpos(1:2);
         cd(findomprefs)
         save('dist2conv.mat', 'wpos')
         cd(olddir)
         results=[];
      end
   end
   

   % Callbacks that handle component events
   methods (Access = private)

      % Code that executes after component creation
      function startup(app)
         set(0,'ShowHiddenHandles','on')
         ch = get(0,'Children');
         keeper=0;
         for i=1:length(ch)
            if strcmpi(ch(i).Name, 'dist2conv') ...
                  && strcmpi(ch(i).Tag,'control')
               keeper=i;
            end
         end
         if keeper>=1
            ML_W_switch('mlw')
            figure(ch(keeper))
            delete(app)
            return
         end
         
         olddir=pwd;
         cd(findomprefs)
         scrsize=get(0,'ScreenSize');
         if exist('dist2conv.mat','file')
            load('dist2conv.mat','wpos');
         else
            wpos = [scrsize(3)/2 scrsize(4)/2];
         end
         cd(olddir)
         cursize=app.dist2convUIFigure.Position;
         newsize = [wpos(1:2) cursize(3) cursize(4)];
         app.dist2convUIFigure.Position = newsize;
         app.dist2convUIFigure.Color=[.94 .94 .94];
         app.dist2convUIFigure.Tag = 'control';
         app.dist2convUIFigure.NextPlot = 'new';
      end

      % Value changed function: IPD
      function ipd_updated(app, event)
         ipd   = app.IPD.Value; %#ok<NASGU> 
         app.verge_angle.Value = 0;
         app.verge_diopt.Value = 0;
         app.distance.Value = 0;
         saveprefs(app);
      end

      % Value changed function: verge_angle
      function v_updated(app, event)
         v_ang = app.verge_angle.Value;
         ipd   = app.IPD.Value;
         dist = round(ipd/(tand(v_ang)),2);
         app.verge_diopt.Value = round(v_ang*1.75);
         app.distance.Value = dist;
      end

      % Value changed function: distance
      function dist_updated(app, event)
         ipd   = app.IPD.Value;
         dist = app.distance.Value;
         v_ang = 2*atand(ipd/(2*dist));
         app.verge_angle.Value = round(v_ang,2);
         app.verge_diopt.Value = round(v_ang*1.75,2);
         saveprefs(app);
      end

      % Value changed function: verge_diopt
      function diopt_updated(app, event)
         diopt = app.verge_diopt.Value;
         ipd   = app.IPD.Value;
         v_ang = diopt/1.75;
         dist = round(ipd/(2*tand(v_ang)),2);
         app.verge_angle.Value = round(v_ang*1.75);
         app.distance.Value = dist;
      end

      % Close request function: dist2convUIFigure
      function dist2convUIFigureCloseRequest(app, event)
         saveprefs(app);
         delete(app)         
      end
   end

   % Component initialization
   methods (Access = private)

      % Create UIFigure and components
      function createComponents(app)
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         app.dist2convUIFigure = uifigure('Visible', 'off');
         app.dist2convUIFigure.Name = 'dist2conv';
         app.dist2convUIFigure.CloseRequestFcn = createCallbackFcn(app, @dist2convUIFigureCloseRequest, true);
         app.IPDLabel = uilabel(app.dist2convUIFigure);
         app.IPDLabel.Text = 'IPD';
         app.IPD = uieditfield(app.dist2convUIFigure, 'numeric');
         app.IPD.Limits = [20 80];
         app.IPD.ValueChangedFcn = createCallbackFcn(app, @ipd_updated, true);
         app.IPD.Value = 60;
         app.verge_angleLabel = uilabel(app.dist2convUIFigure);
         app.verge_angleLabel.Text = 'Verg Angle (°)';
         app.verge_angle = uieditfield(app.dist2convUIFigure, 'numeric');
         app.verge_angle.Limits = [0 Inf];
         app.verge_angle.ValueDisplayFormat = '%5.2f';
         app.verge_angle.ValueChangedFcn = createCallbackFcn(app, @v_updated, true);
         app.DistancetotgtLabel = uilabel(app.dist2convUIFigure);
         app.DistancetotgtLabel.Text = 'Distance to tgt';
         app.distance = uieditfield(app.dist2convUIFigure, 'numeric');
         app.distance.Limits = [0 10000];
         app.distance.ValueDisplayFormat = '%5.2f';
         app.distance.ValueChangedFcn = createCallbackFcn(app, @dist_updated, true);
         app.verge_dioptLabel = uilabel(app.dist2convUIFigure);
         app.verge_dioptLabel.Text = 'Verg Angle (D)';
         app.verge_diopt = uieditfield(app.dist2convUIFigure, 'numeric');
         app.verge_diopt.Limits = [0 Inf];
         app.verge_diopt.ValueDisplayFormat = '%5.2f';
         app.verge_diopt.ValueChangedFcn = createCallbackFcn(app, @diopt_updated, true);
         % Show the figure after all components are created
         app.dist2convUIFigure.Visible = 'on';
      end
   end

   % App creation and deletion
   methods (Access = public)

      % Construct app
      function app = dist2conv_gui

         % Create UIFigure and components
         createComponents(app)

         % Register the app with App Designer
         registerApp(app, app.dist2convUIFigure)

         % Execute the startup function
         runStartupFcn(app, @startup)

         if nargout == 0
            clear app
         end
      end

      % Code that executes before app deletion
      function delete(app)

         % Delete UIFigure when app is deleted
         delete(app.dist2convUIFigure)
      end
   end
end