classdef datstat < matlab.apps.AppBase

   % Properties that correspond to app components
   properties (Access = public)
      EMDataUIFigure        matlab.ui.Figure
      actionsB              matlab.ui.control.Button
      AboutButton           matlab.ui.control.Button
      details_panel         matlab.ui.container.Panel
      SettingsButton        matlab.ui.control.Button
      do_hh                 matlab.ui.control.CheckBox
      do_hv                 matlab.ui.control.CheckBox
      auto_deblink          matlab.ui.control.CheckBox
      auto_bias             matlab.ui.control.CheckBox
      AtreadLabel           matlab.ui.control.Label
      auto_filt             matlab.ui.control.CheckBox
      samp_freq             matlab.ui.control.NumericEditField
      FreqEditFieldLabel    matlab.ui.control.Label
      num_samps             matlab.ui.control.NumericEditField
      SamplesLabel          matlab.ui.control.Label
      LPFilter              matlab.ui.control.DropDown
      FilterLabel           matlab.ui.control.Label
      ApplyButton           matlab.ui.control.Button
      filt_cutoff           matlab.ui.control.NumericEditField
      CutoffEditFieldLabel  matlab.ui.control.Label
      filt_order            matlab.ui.control.NumericEditField
      OrderEditFieldLabel   matlab.ui.control.Label
      HzLabel               matlab.ui.control.Label
      do_lt                 matlab.ui.control.CheckBox
      do_lv                 matlab.ui.control.CheckBox
      do_lh                 matlab.ui.control.CheckBox
      do_rh                 matlab.ui.control.CheckBox
      do_rt                 matlab.ui.control.CheckBox
      do_rv                 matlab.ui.control.CheckBox
      file_name             matlab.ui.control.EditField
      FileLabel             matlab.ui.control.Label
      showbutt              matlab.ui.control.Button
      growshrinkButt        matlab.ui.control.StateButton
      last_to_base          matlab.ui.control.EditField
      CloseButton           matlab.ui.control.Button
      file_path             matlab.ui.control.TextArea
      PathTextAreaLabel     matlab.ui.control.Label
      put_in_base           matlab.ui.control.Button
      handholder            matlab.ui.control.Button
      remove_data           matlab.ui.control.Button
      loaded_data           matlab.ui.control.ListBox
      Label                 matlab.ui.control.Label
      read_data             matlab.ui.control.Button
   end

   %%%%%%%%%%%%%%%%%%%%%%%%%%%%%
   properties (Access = public)
      loadednames    % names of all loaded data
      currdataname   % data currently selected
      EMD            % current data (in EMdata structure)
      f_info    = {}
      availchan = {}
      busy = 0;
      lastloaded
      lastreadclick = 0
   end


   %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
   methods (Access = private)

      function saveprefs(app)
         app.read_data.Tag='free';
         olddir=pwd;
         wpos=app.EMDataUIFigure.Position;
         wpos=wpos(1:2);
         cd(findomprefs)
         autofilt=app.auto_filt.Value;
         autodeblink=app.auto_deblink.Value;
         autobias=app.auto_bias.Value;
         growshrink=app.growshrinkButt.Value;
         save datamgr.mat  wpos autofilt autodeblink autobias growshrink
         try
            cd(olddir)
         catch
         end
      end %function saveprefs


      function addfocus(app)
         app.read_data.Tag='free';
         webWindows = matlab.internal.webwindowmanager.instance.windowList;
         found=0;
         for ii=1:length(webWindows)
            wwt=webWindows(ii).Title;
            if strcmp(wwt,'EM Data') ...
                  || contains(wwt,'datstat.mlapp')
               %if strcmp(webWindows(ii).Title,'EM Data')
               found = ii;
               win = webWindows(ii);
               break
            end
         end
         if ~found
            disp('Cannot seem to find myself!')
            return
         end
         win.FocusGained = {@app.gainedfocus};
         win.FocusLost   = {@app.lostfocus};
      end %function addfocus

      function gainedfocus(app,~,~)
         %disp('emdm gained focus')
         try
            update_datalist(app)
         catch
            fprintf('oops in update_datalist!\n');
         end
      end
      function lostfocus(~,~,~)
         %update_datalist(app)
         %disp('emdm lost focus')
      end

   end %%%%% Methods = private

   methods (Access = public)

      function update_datalist(app)
         %app.read_data.Tag='free';
         app.loaded_data.Items={};
         % find all emData sets in base memory
         temp=evalin('base','whos');
         cnt=0;
         tlen=length(temp);
         app.loadednames=cell(tlen,1);
         % load the f_info struct
         for ii=1:tlen
            if strcmpi(temp(ii).class,'emData')
               if strcmpi(temp(ii).name,'ans')
                  continue
               end
               cnt=cnt+1;
               [~,emd_info] = getEMD(temp(ii).name);
               if isempty(emd_info)
                  continue
               else
                  app.loadednames{cnt}=temp(ii).name;
                  app.loaded_data.Items{cnt}=app.loadednames{cnt};
                  app.f_info(cnt).filename=emd_info.filename;
                  app.f_info(cnt).pathname=emd_info.pathname;
                  app.f_info(cnt).samp_freq=emd_info.samp_freq;
                  app.f_info(cnt).cutoff=emd_info.samp_freq/20;
                  app.f_info(cnt).numsamps=emd_info.numsamps;
                  app.f_info(cnt).chan_names=emd_info.chan_names;
               end
            end
         end %for ii

         % is there data? update loaded_data,posibly currdataname
         if ~isempty(app.loaded_data.Items)
            if ~isempty(app.currdataname)
               app.loaded_data.Value = app.currdataname;
            else
               app.loaded_data.Value = app.loaded_data.Items{1};
               app.currdataname = app.loaded_data.Items{1};
            end
            % update current data info fields
            a=find(strcmpi(app.loaded_data.Items,app.currdataname));
            app.samp_freq.Value = app.f_info(a).samp_freq;
            app.num_samps.Value = app.f_info(a).numsamps;
            app.filt_cutoff.Value = app.f_info(a).samp_freq/20;
            app.file_name.Value = app.f_info(a).filename;

            update_finfo(app);
         end
      end %function update_datalist

      function outside_call_to_add_data(app)
         add_data(app)
      end

   end %methods=Private


   % Callbacks that handle component events
   methods (Access = private)

      % Code that executes after component creation
      function startupFcn(app, action)
         % I am both proud and fucking ashamed of this skanky hack.
         % If there is already an open datstat window, delete the
         % new "app" and replace it with the old one, whose datstat
         % struct is stored in its UserData.
         % Shame, shame, shame on us all, MathWorks.
         %
         % Prob no longer needed: appdesigner added "Singleton"
         % (any way to tell if IS a singleton?
         %
         %set(0,'ShowHiddenHandles','on')
         ch = allchild(0);
         app.EMDataUIFigure.NextPlot='new';
         found=zeros(1,length(ch));
         keeper=zeros(1,length(ch));
         for ii=1:length(ch)
            if strcmpi(ch(ii).Name, 'EM Data')
               found(ii)=ii;
               if strcmpi(ch(ii).Tag,'control')
                  keeper(ii)=ii;
               end
            end
         end

         if length(find(keeper>0)) > 1
            disp('Panic! Multiple keeper windows found!')
            keyboard
         elseif length(find(keeper>0))==1
            % control window already exists.
            ML_W_switch('mlw')
            figure(ch(keeper(keeper>0)))
            delete(app.EMDataUIFigure)
            app=ch(keeper(keeper>0)).UserData;
         else
            % create new control window
            olddir=pwd;
            cd(findomprefs)
            scrsize=get(0,'ScreenSize');
            if exist('datamgr.mat','file')
               load datamgr.mat wpos autofilt autobias autodeblink growshrink
               try    app.auto_filt.Value = autofilt;
               catch, app.auto_filt.Value = 1;
               end
               try    app.auto_deblink.Value = autodeblink;
               catch, app.auto_deblink.Value = 1;
               end
               try    app.auto_bias.Value = autobias;
               catch, app.auto_bias.Value = 1;
               end
               try    app.growshrinkButt.Value = growshrink;
               catch, app.growshrinkButt.Value = 1;
               end
            else
               wpos = [scrsize(3)/2 scrsize(4)/2];
            end
            try    cd(olddir);
            catch, end
            cursize=app.EMDataUIFigure.Position;
            newsize = [wpos(1:2) cursize(3) cursize(4)];
            app.EMDataUIFigure.Position = newsize;
            app.loaded_data.UserData = app.f_info;
            app.EMDataUIFigure.Tag = 'control';
            app.EMDataUIFigure.NextPlot = 'new';
            app.EMDataUIFigure.UserData = app;
            app.handholder.UserData = app;

            app.actionsB.Enable = 'off';
            app.remove_data.Enable='off';

            grow_shrink(app);
            update_datalist(app);
            drawnow
            addfocus(app) %end function startup
         end

         if ~exist('action','var')
            action = '';
         end
         switch action
            case {'read','rd','r'}
               add_data(app)
               %return
            case 'remove'
            otherwise
         end
         % end function startup
      end

      % Button pushed function: read_data
      function add_data(app, event)
         update_datalist(app);
         %try to prevent double-click of Read button
         tnow=int64(tic)/1e6;  %%% in msec
         if int64(tnow)-int64(app.lastreadclick) < 1000
            %disp('Currently reading. Click the "h" button to reset.')
            return
         else
            app.read_data.Tag='free';
            app.lastreadclick=fix(tic/1e6);    %%% in msec
         end

         arglist={'null','null','null'};
         numarg=0;
         if app.auto_filt.Value==1
            numarg=numarg+1;
            arglist{numarg}='filter';
         end
         if app.auto_deblink.Value==1
            numarg=numarg+1;
            arglist{numarg}='deblink';
         end
         if app.auto_bias.Value==0
            numarg=numarg+1;
            arglist{numarg}='nobias';
         end
         ML_W_switch('ml')

         % Load data from "rd"
         try
            app.lastreadclick=int64(tic)/1e6;    %%% in msec
            app.read_data.Tag='busy';
            out=rd([],arglist{1},arglist{2},arglist{3});
         catch ME
            disp(ME);
            disp(ME.stack(1));
            app.lastreadclick=0;
            app.read_data.Tag='free';
            fprintf('datstat: "rd" barfed.\n')
            out=[];
         end

         if isempty(out)
            app.lastloaded=[];
            fprintf('datstat: Could not read data. Canceling.\n')
            app.read_data.Tag='free';
            app.lastreadclick=0;
            return
         end

         app.busy=1;
         pause(1);
         if ~isempty(out)
            if app.auto_filt.Value == 0
               app.LPFilter.Value = 'None';
            else
               app.LPFilter.Value = 'Low-Pass';
            end
            loaded=app.loaded_data.Items;
            nextfile=length(loaded)+1;
            sname=strtok(out.filename,'.');
            app.lastloaded=sname;
            app.loaded_data.Items{nextfile}=sname;

            app.f_info = app.loaded_data.UserData;
            app.f_info(nextfile).filename  = out.filename;
            app.f_info(nextfile).pathname  = out.pathname;
            app.f_info(nextfile).samp_freq = out.samp_freq;
            app.f_info(nextfile).filt_cutoff = out.samp_freq/20; %default fs/20
            app.f_info(nextfile).numsamps   = out.numsamps;
            app.f_info(nextfile).chan_names = out.chan_names;
            app.loaded_data.UserData=app.f_info;

            app.file_name.Value = out.filename;
            app.file_path.Value = out.pathname;
            app.num_samps.Value = out.numsamps;
            app.samp_freq.Value = out.samp_freq;
            app.filt_cutoff.Value = out.samp_freq/20;

            assignin('base', sname, out)
            update_datalist(app)
            app.loaded_data.Value = sname;
            app.currdataname = sname;
            update_finfo(app);

            disp(['Data added to the base workspace as ' sname])
            dname = strtok(out.filename,'.');
            emd_extract(dname);
            app.busy=0;
            % doesn't work because mult callers between here and
            % outside of app builder.
            %assignin('caller','rd_out',app.EMD);
            saveprefs(app)
         end %function add_data
      end

      % Button pushed function: remove_data
      function delete_data(app, event)
         app.read_data.Tag='free';
         selected = app.loaded_data.Value;
         try
            evalin('base',['clear ' selected])
         catch
         end
         app.file_name.Value = '';
         app.file_path.Value = '';
         app.num_samps.Value = 0;
         app.samp_freq.Value = 0;
         app.currdataname=[];
         app.do_lh.Text='lh'; app.do_rh.Text='rh';
         app.do_lv.Text='lv'; app.do_rv.Text='rv';
         app.do_lt.Text='lt'; app.do_rt.Text='rt';
         app.do_lh.Value=0; app.do_rh.Value=0;
         app.do_lv.Value=0; app.do_rv.Value=0;
         app.do_lt.Value=0; app.do_rt.Value=0;
         update_datalist(app);
      end

      % Value changed function: loaded_data
      function update_finfo(app, event)
         app.read_data.Tag='free';
         if isempty(app.loaded_data.Value)
            app.file_name.Value = '';
            app.file_path.Value = '';
            app.num_samps.Value = 0;
            app.samp_freq.Value = 0;
            app.do_rh.Enable='off'; app.do_lh.Enable='off';
            app.do_rv.Enable='off'; app.do_lv.Enable='off';
            app.do_rt.Enable='off'; app.do_lt.Enable='off';
            app.do_lh.Text='lh'; app.do_rh.Text='rh';
            app.do_lv.Text='lv'; app.do_rv.Text='rv';
            app.do_lt.Text='lt'; app.do_rt.Text='rt';
            app.do_lh.Value=0; app.do_rh.Value=0;
            app.do_lv.Value=0; app.do_rv.Value=0;
            app.do_lt.Value=0; app.do_rt.Value=0;
            app.actionsB.Enable='off';
            app.remove_data.Enable='off';
            return
         end
         app.currdataname = app.loaded_data.Value;
         app.EMD=getEMD(app.currdataname);
         app.file_name.Value = app.EMD.filename;
         app.file_path.Value = app.EMD.pathname;
         app.num_samps.Value = app.EMD.numsamps;
         app.samp_freq.Value = app.EMD.samp_freq;
         app.do_rh.Enable='off'; app.do_lh.Enable='off';
         app.do_rv.Enable='off'; app.do_lv.Enable='off';
         app.do_rt.Enable='off'; app.do_lt.Enable='off';

         % update channel filter status
         for ii=1:length(app.EMD.chan_names)
            n=app.EMD.chan_names{ii};
            filterable=find(strcmpi({'rh','lh','rv','lv','rt','lt'},n));
            if filterable
               app.(['do_' n]).Enable = 'on';
               if isempty(app.EMD.(n).filt_params.type) || ...
                     strcmpi(app.EMD.(n).filt_params.type,'none')
                  % channel not filtered
                  app.(['do_' n]).Value = 0;
                  app.(['do_' n]).Text = n;

               else
                  % channel filtered
                  ord = num2str(app.EMD.(n).filt_params.order);
                  cut = num2str(app.EMD.(n).filt_params.cutoff);
                  app.(['do_' n]).Value = 1;
                  app.(['do_' n]).Text = [n '  (' ord ',' cut ')'];
               end
            end
         end %for ii

         app.actionsB.Enable='on';
         app.remove_data.Enable='on';

         saveprefs(app) %end function update_finfo
      end

      % Callback function: CloseButton, EMDataUIFigure
      function closefcn(app, event)
         saveprefs(app)
         delete(app.EMDataUIFigure)
      end

      % Button pushed function: put_in_base
      function put_data_in_base(app, event)
         app.read_data.Tag='free';
         value=app.loaded_data.Value;
         if isempty(value)
            disp('No data selected to extract')
            return
         end
         %app.loaded_data.ItemsData;
         emd_extract(value);
         app.put_in_base.UserData = value;

      end

      % Button pushed function: ApplyButton
      function filt_now(app, event)
         app.read_data.Tag='free';
         % is there valid data?
         if isempty(app.currdataname)
            disp('No data selected to filter')
            return
         end

         value = app.LPFilter.Value;
         if strcmpi(value,'None')
            chanlist = app.EMD.chan_names;
            for g=1:length(chanlist)
               chan=chanlist{g};
               filterable=find(strcmpi({'rh','lh','rv','lv','rt','lt'},chan));
               if filterable
                  % copy unfilt to 'pos'
                  app.EMD.(chan).pos = app.EMD.(chan).unfiltered;
                  %reset filt_params fields
                  app.EMD.(chan).filt_params.type = 'none';
                  app.EMD.(chan).filt_params.order = [];
                  app.EMD.(chan).filt_params.cutoff = [];
                  % turn off checkbox, reset text
                  app.(['do_' chan]).Value = 0;
                  app.(['do_' chan]).Text = chan;
               end
            end %for g

         else
            % refilter channels using filt_params field data
            % use checkbox vals to decide which chan to filter
            chanlist = {'lh','rh','lv','rv','lt','rt'};
            for k=1:length(chanlist)
               chan=chanlist{k};
               if app.(['do_' chan]).Value == 1
                  app.EMD.(chan).filt_params.type='butterworth';
                  app.EMD.(chan).filt_params.order=app.filt_order.Value;
                  app.EMD.(chan).filt_params.cutoff=app.filt_cutoff.Value;
                  app.EMD.(chan).filt_params.samp_freq=app.EMD.samp_freq;
                  app.EMD.(chan).pos = ...
                     lpf(app.EMD.(chan).unfiltered,app.filt_order.Value, ...
                     app.filt_cutoff.Value,app.EMD.samp_freq);
                  ord = num2str(app.EMD.(chan).filt_params.order);
                  cut = num2str(app.EMD.(chan).filt_params.cutoff);
                  %app.(['do_' chan]).Value = 1;
                  app.(['do_' chan]).Text = [chan '  (' ord ',' cut ')'];
               else
                  app.EMD.(chan).filt_params.type='none';
                  app.EMD.(chan).filt_params.order=[];
                  app.EMD.(chan).filt_params.cutoff=[];
                  app.EMD.(chan).filt_params.samp_freq=[];
               end
            end %for k

         end

         emd=strtok(app.EMD.filename,'.');
         assignin('base',emd, app.EMD)
         emd_extract(app.currdataname)

      end

      % Button pushed function: showbutt
      function show_file(app, event)
         app.read_data.Tag='free';
         pn = pathclean(app.file_path.Value);
         if isempty(pn{1})
            disp('You must load a data file.')
            beep
         else
            eval( ['! open ' pn{1}] )
         end
      end

      % Button pushed function: handholder
      function handles_to_base(app, event)
         assignin('base','app',app)
         disp('app dumped to base')
         app.read_data.Tag='free';
      end

      % Value changed function: growshrinkButt
      function grow_shrink(app, event)
         app.read_data.Tag='free';
         value = app.growshrinkButt.Value;
         emfigpos=app.EMDataUIFigure.Position;

         if value==1
            %grow
            app.details_panel.Visible='on';
            emfigpos(3)=400;

         elseif value==0
            %shrink
            app.details_panel.Visible='off';
            emfigpos(3)=180;
         end
         app.EMDataUIFigure.Position = emfigpos;

      end

      % Button pushed function: AboutButton
      function AboutButtonPushed(app, event)
         dlg_title='About datstat';
         dlg_text={ ...
            'datstat: Part of OMtoools'; ...
            'Written by Jonathan Jacobs';
            'omlab.org, jxj24@case.edu';
            'ver: 28 Jul 2021'
            };
         figure(app.EMDataUIFigure)
         uiconfirm(app.EMDataUIFigure,dlg_text,dlg_title,...
            'Options', {'Check for updates','OK'}, ...
            'DefaultOption',1,'CancelOption',1, ...
            'CloseFcn',@(src,event)aboutcallback(app,src,event));

         function aboutcallback(~,~,event) %app,src,event
            sel = event.SelectedOption;
            switch sel
               case {'OK'}
                  %
               case {'Check for updates'}
                  disp(' ')
                  omcheck;
               otherwise
                  %
            end
         end %function mycallback
         % AboutButtonPressed end.

      end

      % Button pushed function: SettingsButton
      function SettingsButtonPushed(app, event)
         deblink_set();
         %if isempty(out)
         %
         %end
      end
   end

   % Component initialization
   methods (Access = private)

      % Create UIFigure and components
      function createComponents(app)
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         app.EMDataUIFigure = uifigure('Visible', 'off');
         app.EMDataUIFigure.Name = 'EM Data';
         app.EMDataUIFigure.CloseRequestFcn = createCallbackFcn(app, @closefcn, true);
         app.read_data = uibutton(app.EMDataUIFigure, 'push');
         app.read_data.ButtonPushedFcn = createCallbackFcn(app, @add_data, true);
         app.read_data.Tooltip = {'Load eye-movement data'};
         app.read_data.Text = 'Read';
         app.Label = uilabel(app.EMDataUIFigure);
         app.Label.Text = '';
         app.loaded_data = uilistbox(app.EMDataUIFigure);
         app.loaded_data.Items = {};
         app.loaded_data.ValueChangedFcn = createCallbackFcn(app, @update_finfo, true);
         app.loaded_data.Value = {};
         app.remove_data = uibutton(app.EMDataUIFigure, 'push');
         app.remove_data.ButtonPushedFcn = createCallbackFcn(app, @delete_data, true);
         app.remove_data.Enable = 'off';
         app.remove_data.Tooltip = {'Clear the selected record from memory'};
         app.remove_data.Text = 'Remove';
         app.handholder = uibutton(app.EMDataUIFigure, 'push');
         app.handholder.ButtonPushedFcn = createCallbackFcn(app, @handles_to_base, true);
         app.handholder.Tooltip = {'Dump handle data to the base workspace'};
         app.handholder.Text = 'h';
         app.put_in_base = uibutton(app.EMDataUIFigure, 'push');
         app.put_in_base.ButtonPushedFcn = createCallbackFcn(app, @put_data_in_base, true);
         app.put_in_base.Tooltip = {'Extract eye-movement channels to the base workspace'};
         app.put_in_base.Text = 'Put in base';
         app.PathTextAreaLabel = uilabel(app.EMDataUIFigure);
         app.PathTextAreaLabel.Text = 'Path';
         app.file_path = uitextarea(app.EMDataUIFigure);
         app.file_path.Editable = 'off';
         app.CloseButton = uibutton(app.EMDataUIFigure, 'push');
         app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @closefcn, true);
         app.CloseButton.Text = 'Close';
         app.last_to_base = uieditfield(app.EMDataUIFigure, 'text');
         app.growshrinkButt = uibutton(app.EMDataUIFigure, 'state');
         app.growshrinkButt.ValueChangedFcn = createCallbackFcn(app, @grow_shrink, true);
         app.growshrinkButt.Tooltip = {'Open Sesame! Or CClose Sesame. (Display extra settings and status)'};
         app.growshrinkButt.Text = '<>';
         app.growshrinkButt.Value = true;
         app.details_panel = uipanel(app.EMDataUIFigure);
         app.showbutt = uibutton(app.details_panel, 'push');
         app.showbutt.ButtonPushedFcn = createCallbackFcn(app, @show_file, true);
         app.showbutt.Tooltip = {'Reveal the data file in your filesystem'};
         app.showbutt.Text = 'Find';
         app.FileLabel = uilabel(app.details_panel);
         app.FileLabel.Text = 'File';
         app.file_name = uieditfield(app.details_panel, 'text');
         app.file_name.Editable = 'off';
         app.file_name.Tooltip = {'File containing the selected data'};
         app.do_rv = uicheckbox(app.details_panel);
         app.do_rv.Enable = 'off';
         app.do_rv.Tooltip = {'Apply to right vertical data'};
         app.do_rv.Text = 'rv';
         app.do_rt = uicheckbox(app.details_panel);
         app.do_rt.Enable = 'off';
         app.do_rt.Tooltip = {'Apply to right torsional data'};
         app.do_rt.Text = 'rt';
         app.do_rh = uicheckbox(app.details_panel);
         app.do_rh.Enable = 'off';
         app.do_rh.Tooltip = {'Apply to right horizontal data'};
         app.do_rh.Text = 'rh';
         app.do_lh = uicheckbox(app.details_panel);
         app.do_lh.Enable = 'off';
         app.do_lh.Tooltip = {'Apply to left horizontal data'};
         app.do_lh.Text = 'lh';
         app.do_lv = uicheckbox(app.details_panel);
         app.do_lv.Enable = 'off';
         app.do_lv.Tooltip = {'Apply to left vertical data'};
         app.do_lv.Text = 'lv';
         app.do_lt = uicheckbox(app.details_panel);
         app.do_lt.Enable = 'off';
         app.do_lt.Tooltip = {'Apply to left torsional data'};
         app.do_lt.Text = 'lt';
         app.HzLabel = uilabel(app.details_panel);
         app.HzLabel.Text = 'Hz';
         app.OrderEditFieldLabel = uilabel(app.details_panel);
         app.OrderEditFieldLabel.Text = 'Order';
         app.filt_order = uieditfield(app.details_panel, 'numeric');
         app.filt_order.ValueDisplayFormat = '%.0f';
         app.filt_order.Tooltip = {'Filter order (Butterworth)'};
         app.filt_order.Value = 4;
         app.CutoffEditFieldLabel = uilabel(app.details_panel);
         app.CutoffEditFieldLabel.Text = 'Cutoff';
         app.filt_cutoff = uieditfield(app.details_panel, 'numeric');
         app.filt_cutoff.Tooltip = {'Top pass frequency'};
         app.ApplyButton = uibutton(app.details_panel, 'push');
         app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @filt_now, true);
         app.ApplyButton.Tooltip = {'Apply filter settings. (Choose "None" to see unfiltered data)'};
         app.ApplyButton.Text = 'Apply';
         app.FilterLabel = uilabel(app.details_panel);
         app.FilterLabel.Text = 'Filter';
         app.LPFilter = uidropdown(app.details_panel);
         app.LPFilter.Items = {'None', 'Low-Pass'};
         app.LPFilter.Tooltip = {'To filter, or not to filter? That is the question'};
         app.LPFilter.Value = 'None';
         app.SamplesLabel = uilabel(app.details_panel);
         app.SamplesLabel.Text = 'Samples';
         app.num_samps = uieditfield(app.details_panel, 'numeric');
         app.num_samps.ValueDisplayFormat = '%.0f';
         app.num_samps.Editable = 'off';
         app.num_samps.Tooltip = {'Number of samples per channel'};
         app.FreqEditFieldLabel = uilabel(app.details_panel);
         app.FreqEditFieldLabel.Text = 'Freq';
         app.samp_freq = uieditfield(app.details_panel, 'numeric');
         app.samp_freq.Editable = 'off';
         app.samp_freq.Tooltip = {'Sampling frequency'};
         app.auto_filt = uicheckbox(app.details_panel);
         app.auto_filt.Tooltip = {'Apply filtering settings when the data is loaded'};
         app.auto_filt.Text = 'Filter';
         app.auto_filt.Value = true;
         app.AtreadLabel = uilabel(app.details_panel);
         app.AtreadLabel.Tooltip = {'Apply filtering settings when the data is loaded'};
         app.AtreadLabel.Text = 'At read:';
         app.auto_bias = uicheckbox(app.details_panel);
         app.auto_bias.Tooltip = {'Apply offset and scale settings when the data is loaded'};
         app.auto_bias.Text = 'Apply bias';
         app.auto_bias.Value = true;
         app.auto_deblink = uicheckbox(app.details_panel);
         app.auto_deblink.Tooltip = {'Attempt to remove blinks form the data when it is loaded'};
         app.auto_deblink.Text = 'Deblink';
         app.auto_deblink.Value = true;
         app.do_hv = uicheckbox(app.details_panel);
         app.do_hv.Enable = 'off';
         app.do_hv.Tooltip = {'Apply to vertical head data'};
         app.do_hv.Text = 'hv';
         app.do_hh = uicheckbox(app.details_panel);
         app.do_hh.Enable = 'off';
         app.do_hh.Tooltip = {'Horizontl head data'};
         app.do_hh.Text = 'hh';
         app.SettingsButton = uibutton(app.details_panel, 'push');
         app.SettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SettingsButtonPushed, true);
         app.SettingsButton.Tooltip = {'View/modify deblink settings'};
         app.SettingsButton.Text = 'Settings';
         app.AboutButton = uibutton(app.EMDataUIFigure, 'push');
         app.AboutButton.ButtonPushedFcn = createCallbackFcn(app, @AboutButtonPushed, true);
         app.AboutButton.Tooltip = {'Who dunnit? Also, check for updates'};
         app.AboutButton.Text = 'About...';
         app.actionsB = uibutton(app.EMDataUIFigure, 'push');
         app.actionsB.Enable = 'off';
         app.actionsB.Text = 'Actions';
         % Show the figure after all components are created
         app.EMDataUIFigure.Visible = 'on';
      end
   end

   % App creation and deletion
   methods (Access = public)

      % Construct app
      function app = datstat(varargin)

         runningApp = getRunningApp(app);

         % Check for running singleton app
         if isempty(runningApp)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EMDataUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
         else

            % Focus the running singleton app
            figure(runningApp.EMDataUIFigure)

            app = runningApp;
         end

         if nargout == 0
            clear app
         end
      end

      % Code that executes before app deletion
      function delete(app)

         % Delete UIFigure when app is deleted
         delete(app.EMDataUIFigure)
      end
   end
end