classdef deblink_set < matlab.apps.AppBase

   % Properties that correspond to app components
   properties (Access = public)
      deblinkUIFig            matlab.ui.Figure
      plot_resCB              matlab.ui.control.CheckBox
      VerboseCB               matlab.ui.control.CheckBox
      DefaultButton           matlab.ui.control.Button
      CancelButton            matlab.ui.control.Button
      MinLabel                matlab.ui.control.Label
      MaxLabel                matlab.ui.control.Label
      acc_val_min             matlab.ui.control.NumericEditField
      vel_val_min             matlab.ui.control.NumericEditField
      pos_val_min             matlab.ui.control.NumericEditField
      DoneButton              matlab.ui.control.Button
      ApplyButton             matlab.ui.control.Button
      DeblinkparametersLabel  matlab.ui.control.Label
      spread_val              matlab.ui.control.NumericEditField
      msecLabel               matlab.ui.control.Label
      useSpreadCB             matlab.ui.control.CheckBox
      acc_val_max             matlab.ui.control.NumericEditField
      degs2Label              matlab.ui.control.Label
      useAccCB                matlab.ui.control.CheckBox
      vel_val_max             matlab.ui.control.NumericEditField
      degsLabel               matlab.ui.control.Label
      useVelCB                matlab.ui.control.CheckBox
      pos_val_max             matlab.ui.control.NumericEditField
      degEditFieldLabel       matlab.ui.control.Label
      usePosCB                matlab.ui.control.CheckBox
   end


   properties (Access = public)
      last_dbvals   % 'dbval' at load time. Updates whenever new val is accepted.
   end

   methods (Access = private)

      % Update dbvals struct
      function update_dbvals_fields(app)
         try    use_pos = app.last_dbvals.use_pos;
         catch, use_pos = 1; end
         try    use_vel = app.last_dbvals.use_vel;
         catch, use_vel = 1; end
         try    use_acc = app.last_dbvals.use_acc;
         catch, use_acc = 1; end
         try    use_spread = app.last_dbvals.use_spread;
         catch, use_spread = 1; end

         try    pos_max = app.last_dbvals.pos_max;
         catch, pos_max = 40; end
         try    pos_min = app.last_dbvals.pos_min;
         catch, pos_min = -40; end
         try    vel_max = app.last_dbvals.vel_max;
         catch, vel_max = 1000; end
         try    vel_min = app.last_dbvals.vel_min;
         catch, vel_min = -1000; end
         try    acc_max = app.last_dbvals.acc_max;
         catch, acc_max = 25000; end
         try    acc_min = app.last_dbvals.acc_min;
         catch, acc_min = -25000; end

         try    spread_ms = app.last_dbvals.spread_ms;
         catch, spread_ms = 10; end
         try    verbose = app.last_dbvals.verbose;
         catch, verbose = 0; end
         try    plot_res = app.last_dbvals.plot_res;
         catch, plot_res = 0; end

         app.usePosCB.Value = use_pos;
         app.useVelCB.Value = use_vel;
         app.useAccCB.Value = use_acc;
         app.useSpreadCB.Value = use_spread;

         app.pos_val_max.Value = pos_max;
         app.pos_val_min.Value = pos_min;
         app.vel_val_max.Value = vel_max;
         app.vel_val_min.Value = vel_min;
         app.acc_val_max.Value = acc_max;
         app.acc_val_min.Value = acc_min;

         app.spread_val.Value  = spread_ms;
         app.plot_resCB.Value  = plot_res;
         app.VerboseCB.Value   = verbose;
      end

      %
      function update_pref_file(app)
         curdir = pwd;
         cd(findomprefs)
         wpos = app.deblinkUIFig.Position;
         dbvals = app.last_dbvals;
         save('deblink_vals.mat','dbvals','wpos');
         cd(curdir)
      end
   end


   % Callbacks that handle component events
   methods (Access = private)

      % Code that executes after component creation
      function startupFcn(app)
         %global samp_freq

         % Look for deblink_vals.mat in omtools_prefs/omprefs
         % If not present, create, using default values
         curdir = pwd;
         try
            cd(findomprefs)
         catch
            errorbeep
            fprintf('Could not find omprefs folder!\n')
            keyboard
         end

         try
            load('deblink_vals.mat','dbvals','wpos')
         catch
            beep
            fprintf('Could not load deblink_vals.mat.\n')
            fprintf('Will try to create new default set.\n')
            dbvals.pos_max = 40;       dbvals.pos_min = -40;
            dbvals.vel_max = 1000;     dbvals.vel_min = -1000;
            dbvals.acc_max = 25000;    dbvals.acc_min = -25000;
            dbvals.spread_ms = 10;
            dbvals.plot_res = 0;
            dbvals.verbose = 0;
            dbvals.use_pos = 1;
            dbvals.use_vel = 1;
            dbvals.use_acc = 1;
            dbvals.use_spread = 1;

            if ~exist('wpos','var')
               wpos = app.deblinkUIFig.Position;
            end
            save('deblink_vals.mat','dbvals','wpos')
         end
         app.last_dbvals = dbvals;

         % Restore GUI window position
         app.deblinkUIFig.Position = wpos;

         % Assign loaded vals to temp "last set"
         app.update_dbvals_fields;

         % Return to original dir
         try
            cd(curdir)
         catch
            beep
            fprintf('Could not return to %s\n',curdir)
         end
      end

      % Button pushed function: DefaultButton
      function DefaultButtonPushed(app, event)
         dbvals.pos_max = 40;       dbvals.pos_min = -40;
         dbvals.vel_max = 1000;     dbvals.vel_min = -1000;
         dbvals.acc_max = 25000;    dbvals.acc_min = -25000;
         dbvals.spread_ms = 10;
         dbvals.plot_res = 0;
         dbvals.verbose = 0;
         dbvals.use_pos = 1;
         dbvals.use_vel = 1;
         dbvals.use_acc = 1;
         dbvals.use_spread = 1;

         app.last_dbvals = dbvals;
         update_dbvals_fields(app);
      end

      % Value changed function: pos_val_max
      function pos_val_maxValueChanged(app, event)
         max_pos = app.pos_val_max.Value;
         min_pos = app.pos_val_min.Value;
         if max_pos <= min_pos
            beep
            % Restore previous pos_val_max
            app.pos_val_max.Value = app.last_dbvals.pos_max;
            return
         end
         % Update property
         app.last_dbvals.pos_max = max_pos;
         % Update deblink_vals.mat
         app.update_pref_file;
      end

      % Value changed function: pos_val_min
      function pos_val_minValueChanged(app, event)
         max_pos = app.pos_val_max.Value;
         min_pos = app.pos_val_min.Value;
         if max_pos <= min_pos
            beep
            % Restore previous pos_val_max
            app.pos_val_min.Value = app.last_dbvals.pos_min;
            return
         end
         % Update property
         app.last_dbvals.pos_min = min_pos;
         % Update deblink_vals.mat
         app.update_pref_file;
      end

      % Value changed function: vel_val_max
      function vel_val_maxValueChanged(app, event)
         max_vel = app.vel_val_max.Value;
         min_vel = app.vel_val_min.Value;
         if max_vel <= min_vel
            beep
            % Restore previous pos_val_max
            app.vel_val_max.Value = app.last_dbvals.vel_max;
            return
         end
         % Update property
         app.last_dbvals.vel_max = max_vel;
         % Update deblink_vals.mat
         app.update_pref_file;
      end

      % Value changed function: vel_val_min
      function vel_val_minValueChanged(app, event)
         max_vel = app.vel_val_max.Value;
         min_vel = app.vel_val_min.Value;
         if max_vel <= min_vel
            beep
            % Restore previous pos_val_max
            app.vel_val_min.Value = app.last_dbvals.vel_min;
            return
         end
         % Update property
         app.last_dbvals.vel_min = min_vel;
         % Update deblink_vals.mat
         app.update_pref_file;
      end

      % Value changed function: acc_val_max
      function acc_val_maxValueChanged(app, event)
         max_acc = app.acc_val_max.Value;
         min_acc = app.acc_val_min.Value;
         if max_acc <= min_acc
            beep
            % Restore previous pos_val_max
            app.acc_val_max.Value = app.last_dbvals.acc_max;
            return
         end
         % Update property
         app.last_dbvals.acc_max = max_acc;
         % Update deblink_vals.mat
         app.update_pref_file;
      end

      % Value changed function: acc_val_min
      function acc_val_minValueChanged(app, event)
         max_acc = app.acc_val_max.Value;
         min_acc = app.acc_val_min.Value;
         if max_acc <= min_acc
            beep
            % Restore previous pos_val_max
            app.acc_val_min.Value = app.last_dbvals.acc_min;
            return
         end
         % Update property
         app.last_dbvals.acc_min = min_acc;
         % Update deblink_vals.mat
         app.update_pref_file;
      end

      % Value changed function: spread_val
      function spread_valValueChanged(app, event)
         spread_ms = app.spread_val.Value;
         if spread_ms < 0
            beep
            app.spread_val.Value = app.last_dbvals.spread_ms;
            return
         end
         app.last_dbvals.spread_ms = spread_ms;
      end

      % Close request function: deblinkUIFig
      function deblinkUIFigCloseRequest(app, event)
         app.update_pref_file;
         delete(app)
      end

      % Button pushed function: DoneButton
      function DoneButtonPushed(app, event)
         app.deblinkUIFigCloseRequest
      end

      % Button pushed function: ApplyButton
      function ApplyButtonPushed(app, event)
         app.update_pref_file;
         fprintf('Current deblink settings:\n')
         pos_max = app.last_dbvals.pos_max;
         pos_min = app.last_dbvals.pos_min;
         vel_max = app.last_dbvals.vel_max;
         vel_min = app.last_dbvals.vel_min;
         acc_max = app.last_dbvals.acc_max;
         acc_min = app.last_dbvals.acc_min;
         spread_ms = app.last_dbvals.spread_ms;

         use_pos = app.last_dbvals.use_pos;
         use_vel = app.last_dbvals.use_vel;
         use_acc = app.last_dbvals.use_acc;
         use_spread = app.last_dbvals.use_spread;

         if use_pos
            fprintf('Pos: [%d %d],  ',pos_min,pos_max);
         else
            fprintf('Pos: not used,  ');
         end
         if use_vel
            fprintf('Vel: [%d %d],  ',vel_min,vel_max);
         else
            fprintf('Vel: not used,  ');
         end
         if use_acc
            fprintf('Acc: [%d %d],  ',acc_min,acc_max);
         else
            fprintf('Acc: not used,  ');
         end
         if use_spread
            fprintf('Spread: %d ms',spread_ms);
         else
            fprintf('Spread: not used');
         end
         fprintf('\n')

      end

      % Value changed function: VerboseCB
      function VerboseCBValueChanged(app, event)
         value = app.VerboseCB.Value;
         app.last_dbvals.verbose = value;
         app.update_pref_file;
      end

      % Value changed function: plot_resCB
      function plot_resCBValueChanged(app, event)
         value = app.plot_resCB.Value;
         app.last_dbvals.plot_res = value;
         app.update_pref_file;
      end

      % Value changed function: usePosCB
      function usePosCBValueChanged(app, event)
         value = app.usePosCB.Value;
         app.last_dbvals.use_pos = value;
         app.update_pref_file;
      end

      % Value changed function: useVelCB
      function useVelCBValueChanged(app, event)
         value = app.useVelCB.Value;
         app.last_dbvals.use_vel = value;
         app.update_pref_file;
      end

      % Value changed function: useAccCB
      function useAccCBValueChanged(app, event)
         value = app.useAccCB.Value;
         app.last_dbvals.use_acc = value;
         app.update_pref_file;
      end

      % Value changed function: useSpreadCB
      function useSpreadCBValueChanged(app, event)
         value = app.useSpreadCB.Value;
         app.last_dbvals.use_spread = value;
         app.update_pref_file;
      end
   end

   % Component initialization
   methods (Access = private)

      % Create UIFigure and components
      function createComponents(app)
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         app.deblinkUIFig = uifigure('Visible', 'off');
         app.deblinkUIFig.Name = 'Deblink settings';
         app.deblinkUIFig.CloseRequestFcn = createCallbackFcn(app, @deblinkUIFigCloseRequest, true);
         app.usePosCB = uicheckbox(app.deblinkUIFig);
         app.usePosCB.ValueChangedFcn = createCallbackFcn(app, @usePosCBValueChanged, true);
         app.usePosCB.Text = 'Pos.';
         app.usePosCB.Value = true;
         app.degEditFieldLabel = uilabel(app.deblinkUIFig);
         app.degEditFieldLabel.Text = 'deg';
         app.pos_val_max = uieditfield(app.deblinkUIFig, 'numeric');
         app.pos_val_max.ValueChangedFcn = createCallbackFcn(app, @pos_val_maxValueChanged, true);
         app.useVelCB = uicheckbox(app.deblinkUIFig);
         app.useVelCB.ValueChangedFcn = createCallbackFcn(app, @useVelCBValueChanged, true);
         app.useVelCB.Text = 'Vel.';
         app.useVelCB.Value = true;
         app.degsLabel = uilabel(app.deblinkUIFig);
         app.degsLabel.Text = 'deg/s';
         app.vel_val_max = uieditfield(app.deblinkUIFig, 'numeric');
         app.vel_val_max.ValueChangedFcn = createCallbackFcn(app, @vel_val_maxValueChanged, true);
         app.useAccCB = uicheckbox(app.deblinkUIFig);
         app.useAccCB.ValueChangedFcn = createCallbackFcn(app, @useAccCBValueChanged, true);
         app.useAccCB.Text = 'Acc.';
         app.useAccCB.Value = true;
         app.degs2Label = uilabel(app.deblinkUIFig);
         app.degs2Label.Text = 'deg/s^2';
         app.acc_val_max = uieditfield(app.deblinkUIFig, 'numeric');
         app.acc_val_max.ValueDisplayFormat = '%d';
         app.acc_val_max.ValueChangedFcn = createCallbackFcn(app, @acc_val_maxValueChanged, true);
         app.useSpreadCB = uicheckbox(app.deblinkUIFig);
         app.useSpreadCB.ValueChangedFcn = createCallbackFcn(app, @useSpreadCBValueChanged, true);
         app.useSpreadCB.Text = 'Spread';
         app.useSpreadCB.Value = true;
         app.msecLabel = uilabel(app.deblinkUIFig);
         app.msecLabel.Text = 'msec';
         app.spread_val = uieditfield(app.deblinkUIFig, 'numeric');
         app.spread_val.ValueChangedFcn = createCallbackFcn(app, @spread_valValueChanged, true);
         app.DeblinkparametersLabel = uilabel(app.deblinkUIFig);
         app.DeblinkparametersLabel.Text = 'Deblink parameters:';
         app.ApplyButton = uibutton(app.deblinkUIFig, 'push');
         app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
         app.ApplyButton.Text = 'Apply';
         app.DoneButton = uibutton(app.deblinkUIFig, 'push');
         app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
         app.DoneButton.Text = 'Done';
         app.pos_val_min = uieditfield(app.deblinkUIFig, 'numeric');
         app.pos_val_min.ValueChangedFcn = createCallbackFcn(app, @pos_val_minValueChanged, true);
         app.vel_val_min = uieditfield(app.deblinkUIFig, 'numeric');
         app.vel_val_min.ValueChangedFcn = createCallbackFcn(app, @vel_val_minValueChanged, true);
         app.acc_val_min = uieditfield(app.deblinkUIFig, 'numeric');
         app.acc_val_min.ValueDisplayFormat = '%d';
         app.acc_val_min.ValueChangedFcn = createCallbackFcn(app, @acc_val_minValueChanged, true);
         app.MaxLabel = uilabel(app.deblinkUIFig);
         app.MaxLabel.Text = 'Max';
         app.MinLabel = uilabel(app.deblinkUIFig);
         app.MinLabel.Text = 'Min';
         app.CancelButton = uibutton(app.deblinkUIFig, 'push');
         app.CancelButton.Visible = 'off';
         app.CancelButton.Text = 'Cancel';
         app.DefaultButton = uibutton(app.deblinkUIFig, 'push');
         app.DefaultButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultButtonPushed, true);
         app.DefaultButton.Text = 'Default';
         app.VerboseCB = uicheckbox(app.deblinkUIFig);
         app.VerboseCB.ValueChangedFcn = createCallbackFcn(app, @VerboseCBValueChanged, true);
         app.VerboseCB.Text = 'Verbose';
         app.plot_resCB = uicheckbox(app.deblinkUIFig);
         app.plot_resCB.ValueChangedFcn = createCallbackFcn(app, @plot_resCBValueChanged, true);
         app.plot_resCB.Text = 'Plot results';
         % Show the figure after all components are created
         app.deblinkUIFig.Visible = 'on';
      end
   end

   % App creation and deletion
   methods (Access = public)

      % Construct app
      function app = deblink_set

         runningApp = getRunningApp(app);

         % Check for running singleton app
         if isempty(runningApp)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.deblinkUIFig)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)
         else

            % Focus the running singleton app
            figure(runningApp.deblinkUIFig)

            app = runningApp;
         end

         if nargout == 0
            clear app
         end
      end

      % Code that executes before app deletion
      function delete(app)

         % Delete UIFigure when app is deleted
         delete(app.deblinkUIFig)
      end
   end
end