classdef vrgcal_gui < matlab.apps.AppBase

   % Properties that correspond to app components
   properties (Access = public)
      VrgCalUIFig               matlab.ui.Figure
      UseDeblinkCB              matlab.ui.control.CheckBox
      AboutButton               matlab.ui.control.Button
      ApplyCalButton            matlab.ui.control.Button
      POresults_fn              matlab.ui.control.EditField
      PO_resultsfileLabel       matlab.ui.control.Label
      MLresults_fn              matlab.ui.control.EditField
      ML_resultsfileLabel       matlab.ui.control.Label
      curr_sel_DD               matlab.ui.control.DropDown
      CurrentdataDropDownLabel  matlab.ui.control.Label
      genButton                 matlab.ui.control.Button
      vrgbias_fn                matlab.ui.control.EditField
      VrgbiasfileLabel          matlab.ui.control.Label
      vrgcalstr                 matlab.ui.control.EditField
      CalstrEditFieldLabel      matlab.ui.control.Label
      CreateCalButton           matlab.ui.control.Button
      hguiButton                matlab.ui.control.Button
      textlabel1                matlab.ui.control.Label
      caldistsStr               matlab.ui.control.EditField
      CaldistsLabel             matlab.ui.control.Label
   end

   %%% vrgcal_gui: front end for vergence calibration. Use this to select
   %%% emData (already loaded by 'datstat'), to create a vergence
   %%% calibration, and to apply a vergence calibration to the emData.
   %%% Applying the vergence calibration will create a new emData variable
   %%% in the base workspace, taking the its name and appending '_vcal'.
   %%%
   %%% Reads tgtpos and testresults from the initial '_results.mat' file
   %%% created by the experiment, and backs up the file as '_ORIGres.mat'
   %%% if the backup does not already exist.
   %%% Calculates start and stop times (and indices), updated target
   %%% position, and the vergence-shifted segments of data, and saves them
   %%% back into '_results.mat'.
   %%%
   %%% Requires: 'vrgcal_get','vrgbiasgen','vrgcal_apply','vrgcal',
   %%% 'getsegs','emcopy',find_tgt_segs
   
%{
Order of operations:
Make sure eye-movement data file has been loaded using 'datstat'.
Select using 'Current data' in the 'Vergence Calibration' control figure
If there is not already a previous existing calibration, you will need to
generate one using "Create Cal". Follow the onscreen prompts to perform vergence
calibration for each desired channel and when finished, copy the values to the
vrgbias file, clear and reload the data in "datstat", and reselect it in the
vergence calibration control window.
Use "Apply Cal" to load and apply the values from the vrgbias file. It will
create a figure showing the results of the calibration, and will place a
calibrated version of the data (appended with '_vcal') in the base workspace.
%}


   properties (Access = public)
      vb       % struct of vrgdists,zeros loaded from vrgbias file.
      emdata   % currently selected data (includes EMD)
      ml_res   % _results.mat for emdat
      po_res   % _poem_results.mat for emdat
      all_segs % prob will be unused
      distlist % struct containing tgt distance and time.
      seglist  % distance and time segments that correspond to it.
   end


   % Public methods
   methods (Access = public)

      function apply_vrgcal(app)
         %results=0;
         if ~isempty(app),end
         vrgcal_apply(app)
      end

   end

   % Private methods
   methods (Access = private)

      % Add focus functions to window gain/lose focus.
      function addfocus(app)
         webWindows = matlab.internal.webwindowmanager.instance.windowList;
         found=0;
         for ii=1:length(webWindows)
            wwt=webWindows(ii).Title;
            if strcmp(wwt,'Vergence Calibration') %%|| contains(wwt,'datstat.mlapp')
               found = ii;
               win = webWindows(ii);
               break
            end
         end
         if ~found
            disp('Cannot seem to find myself!')
            return
         end
         win.FocusGained = {@app.gainedfocus};
         win.FocusLost   = {@app.lostfocus};
      end %function addfocus


      % gainedfocus
      function gainedfocus(app,~,~)
         select_data(app)
         %disp('vrgcal focus')
      end
      function lostfocus(~,~,~)
         %disp('vrgcal lost focus')
      end


      % saveprefs
      function saveprefs(app)
         olddir=pwd;
         wpos=app.VrgCalUIFig.Position;
         wpos=wpos(1:2);
         cd(findomprefs)
         try    save vrgcalmgr.mat  wpos
         catch, fprintf('Could not save "vrgcalmgr.mat" in omprefs.\n')
         end
         try    cd(olddir)
         catch, end
      end %function saveprefs

   end


   % Callbacks that handle component events
   methods (Access = private)

      % Code that executes after component creation
      function startupFcn(app)
         % Restore saved window pos
         curdir=pwd;
         cd(findomprefs)
         if exist('vrgcalmgr.mat','file')
            load vrgcalmgr.mat wpos
            cursize=app.VrgCalUIFig.Position;
            newsize = [wpos(1:2) cursize(3) cursize(4)];
            app.VrgCalUIFig.Position = newsize;
         else
            fprintf('No "vrgcalmgr.mat" in omprefs. I will create it.\n')
            try    save vrgcalmgr.mat
            catch, fprintf('Could not create "vrgcalmgr.mat".\n'); end
         end
         cd(curdir)
         % Init UI vals
         app.genButton.Enable = 'off';
         app.VrgCalUIFig.Tag='vrgcal';
         app.VrgCalUIFig.UserData=app;
         go=app.curr_sel_DD;
         find_loaded_emdm(go);
         % end startupFcn(app)
      end

      % Value changed function: curr_sel_DD
      function select_data(app, event)
         value = app.curr_sel_DD.Value;
         % Do not try to apply to already-vcalibrated data
         if contains(value,'_vcal')
            beep
            app.vrgbias_fn.Value = '';
            app.MLresults_fn.Value='';
            app.POresults_fn.Value='';
            app.caldistsStr.Value='';
            app.ApplyCalButton.Enable='off';
            app.CreateCalButton.Enable='off';
            fprintf('You cannot apply to already-vcalibrated data!\n')
            return
         end

         go=app.curr_sel_DD;

         % actions: "refresh menu", "get new data" and
         if strcmpi(value,'refresh menu')
            find_loaded_emdm(go);
            app.vrgbias_fn.Value = '';
            app.MLresults_fn.Value='';
            app.POresults_fn.Value='';
            app.caldistsStr.Value='';
            app.vb=[];
            app.emdata=[];
            app.ml_res=[];
            app.po_res=[];
            app.all_segs=[];
            return
         elseif strcmpi(value,'get new data')
            %call to current running version of datstat
            app.vrgbias_fn.Value = '';
            app.MLresults_fn.Value='';
            app.POresults_fn.Value='';
            app.caldistsStr.Value='';
            app.vb=[];
            app.emdata=[];
            app.ml_res=[];
            app.po_res=[];
            app.all_segs=[];
            datstat('rd')
            find_loaded_emdm(go);
            return
         end

         % Enable 'Create Cal' and 'Apply Cal' buttons
         app.ApplyCalButton.Enable='on';
         app.CreateCalButton.Enable='on';

         % use the selected EMdata struct.
         dat = select_emdata(go); %(go,action);
         sel = find(strcmpi(dat.lastselname,dat.loadednames));
         pn=dat.f_info(sel).pathname; %, start_dir);
         fn=dat.f_info(sel).filename; %, start_dir);
         app.curr_sel_DD.Tag=fullfile(pn,fn);
         app.emdata=dat;
         emd_extract(dat.lastselname);
         fprintf('%s is now active in base workspace.\n',dat.lastselname);

         % Get vrgbias from file
         vbname=['vrgbias_' dat.lastselname '.txt'];
         if exist(fullfile(pn,vbname),'file')
            app.vrgbias_fn.Value = vbname;
            try    app.vb=vrgcal_get(vbname,fullfile(pn,fn));
            catch, fprintf('vrgbias values did not load.\n'); end
         else
            app.vrgbias_fn.Value = 'make a vcal file->';
            app.genButton.Enable = 'on';
            return
         end

         % Load ML '_results.mat' file, and '_poem_results.mat' file.
         olddir=pwd;
         cd(pn)
         dtemp=dir;
         po_found=0;
         ml_found=0;
         for jj=1:length(dtemp)
            try
               rname=dtemp(jj).name;
            catch
               keyboard
               rname='';
            end
            if contains(rname,'_results.mat')
               if contains(rname,'_poem_results.mat')
                  po_found=1;
                  app.po_res=load(rname);
                  app.POresults_fn.Value=rname;
               else
                  ml_found=1;
                  app.MLresults_fn.Value=rname;
                  app.ml_res=load(rname);
                  % does it have extended structure?
                  if any(contains(fields(app.ml_res),'ml_starts'))
                     fprintf(' * Extended "ml_results" structure found.\n')
                  else
                     status=copyfile(rname,strrep(rname,'_results','_ORIGres'),'f');
                     if status==0
                        fprintf('Could not create a backup of original ML results file.\n')
                        return
                     end
                     res=find_tgt_segs(app.ml_res.h);
                     app.ml_res.ml_starts    = res.ml_starts;
                     app.ml_res.ml_startinds = res.ml_startinds;
                     app.ml_res.ml_tgtpos    = res.ml_tgtpos;
                     app.ml_res.ml_stops     = res.ml_stops;
                     app.ml_res.ml_stopinds  = res.ml_stopinds;
                     fprintf(' * Extended "ml_results" structure created and saved.\n')
                     % Save into COPY of orig _results.
                     name=dtemp(jj).name;
                     temp=app.ml_res;
                     save(name, '-struct', 'temp')
                  end
               end
               if po_found && ml_found
                  break
               end
            else
               % NO '_results.mat' of any kind.
            end
         end

         % Try to load _results files.
         if ~ml_found
            app.MLresults_fn.Value='none found';
            fprintf('No ML "_results.mat" file for this data.\n')
            fprintf('So no target distances are available for cal. Done.\n')
            return
         end
         if ~po_found
            app.po_res=[];
            app.POresults_fn.Value='None found. Using ML_results.';
            fprintf(' * No "_poem_results.mat" file for this data. *\n')
            fprintf(' * You can still analyze MATLAB-only data.    *\n')
         end

         % Find tgtpos distances in the ML _results file.
         tgtpos=app.ml_res.tgtpos;
         caldists=unique([tgtpos.distance]);
         caldists(caldists==0)=[];
         temp=mat2str(caldists);
         temp=strrep(temp,' ',', ');
         temp(temp=='[')='';
         temp(temp==']')='';
         app.caldistsStr.Value=temp;

         % This may not be all that useful, actually. It is probably
         % simpler and sufficient to do a loop in 'vrgcal_apply' to check
         % each segment's distance, and shift the segment by that dist's
         % offset. (Currently only shifting the segs w/that dist AND at
         % 0deg. (Also in 'vrgcal': show only 0 deg, but apply to all?)
         
         % Save all target dist & time to 'distlist' struct.
         % Convert from Eyelink timebase to index into data array.
         % Some older data may not have start_times populated.
         for xx=1:length(tgtpos)
            if isempty(tgtpos(xx).distance)
               app.distlist.dist(xx) = 0;
            else
               app.distlist.dist(xx) = tgtpos(xx).distance;
            end
         end

         %try
         %   d0 = dat.data.start_times(1);
         %catch
         %   d0 = tgtpos(1).when;
         %   fprintf('No start_time for data found!\n')
         %   fprintf('Data timebase will not start at 0.\n')
         %end
         d0 = tgtpos(1).when;
         when = [tgtpos.when] - d0;
         when = when * dat.data.samp_freq/1000;
         app.distlist.when = when;

         % Find ALL data taken at each distance.
         % Get all segments associated with each distance
         for zz = 1:length(caldists)
            app.seglist(zz).dist = caldists(zz);
            temp = find([app.distlist.dist]==caldists(zz));
            app.seglist(zz).segs.num = caldists(zz);
            for yy=1:length(temp)
               app.seglist(zz).segs.start(yy) = tgtpos(temp(yy)).when;
            end
         end

         %dd = tgtpos(1).distance;
         %{
         for zz=2:length([tgtpos.distance])
            % if this distance is the same as the previous, append it.
            if tgtpos(zz).distance == tgtpos(zz-1).distance

            else

            end
         end
         %}

         try    cd(olddir)
         catch, fprintf('Could not return to previous dir: %s\n',olddir); end
         % end select_data(app)
      end

      % Button pushed function: CreateCalButton
      function perform_calibration(app, event)
         % Calls vrgcal.m with this app's handles
         vrgcal(app);
      end

      % Button pushed function: genButton
      function genButtonPushed(app, event)
         % Create vrgbias file for poem data. (Other types (e.g. EL) soon?)
         [pn,fn]=fileparts(app.curr_sel_DD.Tag);
         vrgbiasgen(fn,pn,50,1,{{'rh','lh','rv','lv'}},0);
         fprintf('vrgbias file created. please reselect data.\n')
      end

      % Button pushed function: hguiButton
      function hgui_dump(app, event)
         assignin('base','vrgcal_hgui',app);
         fprintf('handles dumped to base as "vrgcal_hgui"\n');
      end

      % Button pushed function: ApplyCalButton
      function apply_calibration(app, event)
         apply_vrgcal(app);
      end

      % Close request function: VrgCalUIFig
      function VrgCalUIFigCloseRequest(app, event)
         saveprefs(app);
         delete(app)
      end

      % Button pushed function: AboutButton
      function AboutButtonPushed(app, event)
         dlg_title='About vrgcal';
         dlg_text={ ...
            'vrgcal: part of OMtoools'; ...
            'Written by Jonathan Jacobs';
            'omlab.org, jxj24@case.edu';
            'ver: 25 Dec 2020'
            };
         figure(app.VrgCalUIFig)
         uiconfirm(app.VrgCalUIFig,dlg_text,dlg_title,...
            'Options', {'Check for updates','OK'}, ...
            'DefaultOption',1,'CancelOption',1, ...
            'CloseFcn',@(src,event)aboutcallback(app,src,event));

         function aboutcallback(~,~,event) %app,src,event
            sel = event.SelectedOption;
            switch sel
               case {'OK'}
                  %
               case {'Check for updates'}
                  disp(' ')
                  omcheck;
               otherwise
                  %
            end
         end %function mycallback
         % AboutButtonPressed end.
      end
   end

   % Component initialization
   methods (Access = private)

      % Create UIFigure and components
      function createComponents(app)
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         app.VrgCalUIFig = uifigure('Visible', 'off');
         app.VrgCalUIFig.Name = 'Vergence Calibration';
         app.VrgCalUIFig.CloseRequestFcn = createCallbackFcn(app, @VrgCalUIFigCloseRequest, true);
         app.CaldistsLabel = uilabel(app.VrgCalUIFig);
         app.CaldistsLabel.Text = 'Cal dists';
         app.caldistsStr = uieditfield(app.VrgCalUIFig, 'text');
         app.caldistsStr.Editable = 'off';
         app.caldistsStr.Tooltip = {'Separate entries with commas'};
         app.textlabel1 = uilabel(app.VrgCalUIFig);
         app.textlabel1.Text = 'read from _results files';
         app.hguiButton = uibutton(app.VrgCalUIFig, 'push');
         app.hguiButton.ButtonPushedFcn = createCallbackFcn(app, @hgui_dump, true);
         app.hguiButton.Text = 'hgui';
         app.CreateCalButton = uibutton(app.VrgCalUIFig, 'push');
         app.CreateCalButton.ButtonPushedFcn = createCallbackFcn(app, @perform_calibration, true);
         app.CreateCalButton.Enable = 'off';
         app.CreateCalButton.Text = 'Create Cal';
         app.CalstrEditFieldLabel = uilabel(app.VrgCalUIFig);
         app.CalstrEditFieldLabel.Text = 'Cal str:';
         app.vrgcalstr = uieditfield(app.VrgCalUIFig, 'text');
         app.vrgcalstr.Editable = 'off';
         app.VrgbiasfileLabel = uilabel(app.VrgCalUIFig);
         app.VrgbiasfileLabel.Text = 'Vrg bias file';
         app.vrgbias_fn = uieditfield(app.VrgCalUIFig, 'text');
         app.vrgbias_fn.Editable = 'off';
         app.genButton = uibutton(app.VrgCalUIFig, 'push');
         app.genButton.ButtonPushedFcn = createCallbackFcn(app, @genButtonPushed, true);
         app.genButton.Text = 'gen';
         app.CurrentdataDropDownLabel = uilabel(app.VrgCalUIFig);
         app.CurrentdataDropDownLabel.Text = 'Current data';
         app.curr_sel_DD = uidropdown(app.VrgCalUIFig);
         app.curr_sel_DD.Items = {'Refresh menu', 'Get new data'};
         app.curr_sel_DD.ValueChangedFcn = createCallbackFcn(app, @select_data, true);
         app.curr_sel_DD.Value = 'Refresh menu';
         app.ML_resultsfileLabel = uilabel(app.VrgCalUIFig);
         app.ML_resultsfileLabel.Text = 'ML_results file';
         app.MLresults_fn = uieditfield(app.VrgCalUIFig, 'text');
         app.MLresults_fn.Editable = 'off';
         app.PO_resultsfileLabel = uilabel(app.VrgCalUIFig);
         app.PO_resultsfileLabel.Text = 'PO_results file';
         app.POresults_fn = uieditfield(app.VrgCalUIFig, 'text');
         app.POresults_fn.Editable = 'off';
         app.ApplyCalButton = uibutton(app.VrgCalUIFig, 'push');
         app.ApplyCalButton.ButtonPushedFcn = createCallbackFcn(app, @apply_calibration, true);
         app.ApplyCalButton.Enable = 'off';
         app.ApplyCalButton.Text = 'Apply Cal';
         app.AboutButton = uibutton(app.VrgCalUIFig, 'push');
         app.AboutButton.ButtonPushedFcn = createCallbackFcn(app, @AboutButtonPushed, true);
         app.AboutButton.Text = 'About...';
         app.UseDeblinkCB = uicheckbox(app.VrgCalUIFig);
         app.UseDeblinkCB.Text = {'Use'; 'Deblink'};
         % Show the figure after all components are created
         app.VrgCalUIFig.Visible = 'on';
      end
   end

   % App creation and deletion
   methods (Access = public)

      % Construct app
      function app = vrgcal_gui

         runningApp = getRunningApp(app);

         % Check for running singleton app
         if isempty(runningApp)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VrgCalUIFig)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)
         else

            % Focus the running singleton app
            figure(runningApp.VrgCalUIFig)

            app = runningApp;
         end

         if nargout == 0
            clear app
         end
      end

      % Code that executes before app deletion
      function delete(app)

         % Delete UIFigure when app is deleted
         delete(app.VrgCalUIFig)
      end
   end
end