classdef mlw_dummy < matlab.apps.AppBase

   % Properties that correspond to app components
   properties (Access = public)
      mlw_dummyUIFig  matlab.ui.Figure
      mlwdummyLabel   matlab.ui.control.Label
   end

   
   methods (Access = private)
      
      function addfocus(app)
         %disp('mlwd add focus')
         webWindows = matlab.internal.webwindowmanager.instance.windowList;
         found=0;
         for ii=1:length(webWindows)
            wwt=webWindows(ii).Title;
            if contains(wwt,'mlwdum')
               found = ii;
               win = webWindows(ii);
               break
            end
         end
         if ~found
            disp('Cannot seem to find myself!')
            return
         end
         win.FocusGained = {@app.gainedfocus};
         win.FocusLost   = {@app.lostfocus};
      end %function addfocus
      
      function gainedfocus(app,~,~)
         try
            f=app.mlw_dummyUIFig;
            %disp('mlwd gained focus')
            %pause(2)
            f.WindowState='minimized';
            %ML_W_switch('ml')
         catch
         end
      end
      function lostfocus(~,~,~)
         try
            f=app.mlw_dummyUIFig;
            %disp('mlwd lost focus')
            %pause(2)
            f.WindowState='minimized';
            %ML_W_switch('ml')
         catch
         end
      end
   end
   

   % Callbacks that handle component events
   methods (Access = private)

      % Code that executes after component creation
      function startupFcn(app, vis)
         %app.mlw_dummyUIFig.Visible='off';
         %disp('vis off')
         f=app.mlw_dummyUIFig;
         pause(1)
         f.WindowState='minimized';
         addfocus(app) %end function startup
      end
   end

   % Component initialization
   methods (Access = private)

      % Create UIFigure and components
      function createComponents(app)
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         app.mlw_dummyUIFig = uifigure('Visible', 'off');
         app.mlw_dummyUIFig.Name = 'mlwdum';
         app.mlwdummyLabel = uilabel(app.mlw_dummyUIFig);
         app.mlwdummyLabel.Text = 'mlwdummy';
         % Show the figure after all components are created
         app.mlw_dummyUIFig.Visible = 'on';
      end
   end

   % App creation and deletion
   methods (Access = public)

      % Construct app
      function app = mlw_dummy(varargin)

         runningApp = getRunningApp(app);

         % Check for running singleton app
         if isempty(runningApp)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.mlw_dummyUIFig)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
         else

            % Focus the running singleton app
            figure(runningApp.mlw_dummyUIFig)

            app = runningApp;
         end

         if nargout == 0
            clear app
         end
      end

      % Code that executes before app deletion
      function delete(app)

         % Delete UIFigure when app is deleted
         delete(app.mlw_dummyUIFig)
      end
   end
end